classdef effects < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        SAVEButton         matlab.ui.control.Button
        ImageAxes          matlab.ui.control.UIAxes
        SharpenButton      matlab.ui.control.Button
        BWButton           matlab.ui.control.Button
        BrightenButton     matlab.ui.control.Button
        ResetButton        matlab.ui.control.Button
        ImageenhanceLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        %CallingApp % Main app object 
        wrapped
        processing
    end
    
    methods (Access = private)
        
        function out = Threshold(app,in, th)
            [row, col]=size(in);
            out=in;
            for i=1:row
                for j=1:col
                    if in(i,j)<=th
                        out(i,j)=0;
                    else
                        out(i,j)=255;
                    end
                end
            end
            out=uint8(out);
            out = imgaussfilt(out,0.4);
        end
        
        
        function threshold = Otsu(app,in)    
        maxGray = max(max(in));    
        minGray = min(min(in));    
        t = double(minGray:maxGray);    
        [M,N] = size(in);     
        for k = 1:length(t)    
            tk = t(1,k);  
            %pixels of foreground and background  
            iforeground = 0;  
            ibackground = 0;  
            %sum of gray value of foreground and background  
            foreground = 0;  
            background = 0;  
            for i = 1:M  
                for j = 1:N  
                    tmp = in(i,j);  
                    if(tmp >= tk)    
                        iforeground = iforeground + 1;  
                        foreground = foreground + double(tmp);  
                    else    
                        ibackground = ibackground + 1;  
                        background = background + double(tmp);  
                    end  
                end  
            end  
            w0 = iforeground / (M*N);  
            w1 = ibackground / (M*N);  
            u0 = foreground / iforeground;  
            u1 = background / ibackground;  
            t(2,k) = w0*w1*(u0-u1)*(u0-u1);  
        end  
        %maximum value of the variance in the second row, ignoring NaN 
        omax = max(t(2,:));    
        index = find(t(2,:) >= omax);    
        t = t(1,index);  
        threshold = t;
        end
        
        
        function out= Laplace(app,I)
            I=double(I);
            [M,N]=size(I);
            B=zeros(size(I));
            w=1;    % parameter w
            for x=2:M-1
                for y=2:N-1
            B(x,y)=w*(I(x+1,y)+I(x-1,y)+I(x,y+1)+I(x,y-1)-4*I(x,y));    % apply Laplace filter
                end 
            end
            I=uint8(I);
            B=uint8(B);
            out=I-B;
        end
        
        function out = Brighten(app,in)
            [M,N]=size(in);
            out=in;
            for i=1:M
            	for j=1:N
            		out(i,j)=in(i,j)+25;
            	end
            end
        end
        

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Iwrap)
            %app.CallingApp = mainapp;
           
            app.wrapped=Iwrap;
            app.processing=app.wrapped;
            imshow(app.processing,'Parent',app.ImageAxes,"Border","tight");
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.processing=app.wrapped;
            imshow(app.processing,'Parent',app.ImageAxes,"Border","tight");
        end

        % Button pushed function: BrightenButton
        function BrightenButtonPushed(app, event)
            app.processing = Brighten(app,app.processing);
            imshow(app.processing,'Parent',app.ImageAxes,"Border","tight");
        end

        % Button pushed function: BWButton
        function BWButtonPushed(app, event)
            th=Otsu(app,app.processing);
            app.processing=Threshold(app,app.processing,th);
            imshow(app.processing,'Parent',app.ImageAxes,"Border","tight");
        end

        % Button pushed function: SharpenButton
        function SharpenButtonPushed(app, event)
            app.processing= Laplace(app,app.processing);
            imshow(app.processing,'Parent',app.ImageAxes,"Border","tight");
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf';'*.eps'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                imwrite(app.processing,[filepath filename]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SAVEButton = uibutton(app.GridLayout, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.ImageAxes = uiaxes(app.GridLayout);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.SharpenButton = uibutton(app.GridLayout, 'push');
            app.SharpenButton.ButtonPushedFcn = createCallbackFcn(app, @SharpenButtonPushed, true);
            app.SharpenButton.Text = 'Sharpen';
            app.BWButton = uibutton(app.GridLayout, 'push');
            app.BWButton.ButtonPushedFcn = createCallbackFcn(app, @BWButtonPushed, true);
            app.BWButton.Text = 'B/W';
            app.BrightenButton = uibutton(app.GridLayout, 'push');
            app.BrightenButton.ButtonPushedFcn = createCallbackFcn(app, @BrightenButtonPushed, true);
            app.BrightenButton.Text = 'Brighten';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ImageenhanceLabel = uilabel(app.GridLayout);
            app.ImageenhanceLabel.Text = 'Image enhance';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = effects(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end