classdef scanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ChooseyourdocumentLabel  matlab.ui.control.Label
        StandardfileA4Button     matlab.ui.control.Button
        CLICKADOCUMENTTYPELabel  matlab.ui.control.Label
        PassportButton           matlab.ui.control.Button
        CardButton               matlab.ui.control.Button
    end

    properties (Access = public)
        
        im % Image to be process
        Iwrap
        DialogApp % Dialog box app
        Papertype %='StdPaper'
    end
    properties (Access = private)
        
    end


    methods (Access = private)
        function out = Median_Filter (app,in)
        out=zeros(size(in));
        for i= 2:size(in,1)-1
            for j=2:size(in,2)-1
                window=zeros(9,1);
                k=1;
                for x=1:3
                    for y=1:3
                        window(k)=in(i+x-2,j+y-2);
                        k=k+1;
                    end
                end
                med=sort(window);
                out(i,j)=med(5);     
            end
        end
        out=uint8(out);
        end
        function outputWrap = PerspectiveWrap(app,I)
            hpaper=2970; %default A4
            wpaper=2100; 
            papertype=app.Papertype;
            if (strcmp(papertype,'StdPaper'))
                hpaper=2970;
                wpaper=2100;
                [h,w]=size(I);
                if h<w
                    I=rot90(I,3);
                end
            end
        
            if (strcmp(papertype,'Passport'))
                hpaper=880*2;
                wpaper=1250*2;
            end
        
            if (strcmp(papertype,'Card'))
                hpaper=1080;
                wpaper=1712;
            end
        
        
            %msgfig = msgbox(' in next pop-up window','Reminder','modal');
            %uiwait(msgfig)
            %fig=uifigure;
            %uialert(fig,'Click the four corners of your document in the next window', ...'Program Information','Icon','info','CloseFcn','uiresume(fig)')%
            
            fig=warndlg('Click the four corners of your document in the next window','Program Information','modal');
            uiwait(fig)
            
            figure;
            imshow(I);
            pTemp=ginput(4);
            pI=pTemp;
            mind=100000;
            maxd=1;
            for i=1:4
                d2=pTemp(i,1)^2+pTemp(i,2)^2;
                if d2<mind
                    mind=d2;
                    pI(1,1)=pTemp(i,1);
                    pI(1,2)=pTemp(i,2);
                end
            end
            for i=1:4
                d2=pTemp(i,1)^2+pTemp(i,2)^2;
                if d2>maxd
                    maxd=d2;
                    pI(4,1)=pTemp(i,1);
                    pI(4,2)=pTemp(i,2);
                end
            end
            for i=1:4
                d2=pTemp(i,1)^2+pTemp(i,2)^2;
                if mind<d2&&d2<maxd&&pTemp(i,1)<pTemp(i,2)  
                    pI(2,1)=pTemp(i,1);
                    pI(2,2)=pTemp(i,2);
                end
            end
            for i=1:4
                d2=pTemp(i,1)^2+pTemp(i,2)^2;
                if mind<d2&&d2<maxd&&pTemp(i,1)>pTemp(i,2)  
                    pI(3,1)=pTemp(i,1);
                    pI(3,2)=pTemp(i,2);
                end
            end
            close;
        
            %figure;
            %t=tiledlayout(1,3);
            %t.TileSpacing = 'compact';
            %t.Padding = 'compact';
            %nexttile;imshow(I);title('Original');
        
            I=uint16(I);
            I(uint16(pI(1,2)),uint16(pI(1,1)))=9999;%mark
            I(uint16(pI(4,2)),uint16(pI(4,1)))=9999;%mark
            pP=[1,1;1,hpaper;wpaper,1;wpaper,hpaper];


            tform= fitgeotrans(pI,pP,'projective');
            outputWrap=imwarp(I,tform,'linear');
            %outputWrap=uint8(zeros(hpaper,wpaper));
        
            %outputWrap=imcrop(outputWrap);
            [htemp,wtemp]=size(outputWrap);
        
        
            flag1=0;
            for x=1:htemp
                for y=1:wtemp
                    if outputWrap(x,y)>255
                        lucut1=x;
                        lucut2=y;
                        flag1=1;
                    end
                end
                if flag1==1
                    break
                end
            end
    
            flag2=0;
            for x=1:htemp
                for y=1:wtemp
                    if outputWrap(htemp-x+1,wtemp-y+1)>255
                        rdcut1=htemp-x+1;
                        rdcut2=wtemp-y+1;
                        flag2=1;
                    end
                end
                if flag2==1
                    break
                end
             end


            outputWrap=uint8(outputWrap);
            %nexttile;imshow(outputWrap);title('Wraped');
            %hold on;
            %plot(lucut2,lucut1,'r.');
            %plot(rdcut2,rdcut1,'r.');
            rect = [lucut2, lucut1, rdcut2-lucut2, rdcut1-lucut1];
            outputWrap=imcrop(outputWrap,rect);
        
            %nexttile;imshow(outputWrap);title('Cropped');
    
        end

        function loadim(app)
             % Display uigetfile dialog
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(filepath))
                fullname = [filepath, filename];
                app.im = rgb2gray(imread(fullname));
            end            
            app.Iwrap=PerspectiveWrap(app,app.im);
            %app.DialogApp = effects(app,app.Iwrap);
            app.Iwrap=Median_Filter(app,app.Iwrap);
            effects(app.Iwrap);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: StandardfileA4Button
        function StandardfileA4ButtonPushed(app, event)
            app.Papertype='StdPaper';
            loadim(app);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: CardButton
        function CardButtonPushed(app, event)
            app.Papertype='Card';
            loadim(app);

        end

        % Button pushed function: PassportButton
        function PassportButtonPushed(app, event)
            app.Papertype='Passport';
            loadim(app);
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ChooseyourdocumentLabel = uilabel(app.UIFigure);
            app.ChooseyourdocumentLabel.Text = 'Choose your document:';
            app.StandardfileA4Button = uibutton(app.UIFigure, 'push');
            app.StandardfileA4Button.ButtonPushedFcn = createCallbackFcn(app, @StandardfileA4ButtonPushed, true);
            app.StandardfileA4Button.Text = 'Standard file (A4)';
            app.CLICKADOCUMENTTYPELabel = uilabel(app.UIFigure);
            app.CLICKADOCUMENTTYPELabel.Text = 'CLICK A DOCUMENT TYPE';
            app.PassportButton = uibutton(app.UIFigure, 'push');
            app.PassportButton.ButtonPushedFcn = createCallbackFcn(app, @PassportButtonPushed, true);
            app.PassportButton.Text = 'Passport';
            app.CardButton = uibutton(app.UIFigure, 'push');
            app.CardButton.ButtonPushedFcn = createCallbackFcn(app, @CardButtonPushed, true);
            app.CardButton.Text = 'Card';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scanner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end